classdef EQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        pausaButton          matlab.ui.control.Button
        WAVEQrLabel          matlab.ui.control.Label
        VolumenKnob          matlab.ui.control.Knob
        VolumenKnobLabel     matlab.ui.control.Label
        PlayButton           matlab.ui.control.Button
        BajosSlider          matlab.ui.control.Slider
        BajosSliderLabel     matlab.ui.control.Label
        MediosSlider         matlab.ui.control.Slider
        MediosSliderLabel    matlab.ui.control.Label
        AltosSlider          matlab.ui.control.Slider
        AltosSliderLabel     matlab.ui.control.Label
        BuscarArchivoButton  matlab.ui.control.Button
        UIAxes5              matlab.ui.control.UIAxes
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    
    properties (Access = public)
       y % variable correspondiente a la matriz del audio
       fullpathname %datos completos de la ubicación del archivo
       fs %Frecuencia
       bandas = [true,true,true]
       A %procesamiento para filtrar
       A2
       A3
       y2 %variable de audio resultante 
       y3
       y4
       volume %volumen de salida
       yt %variable de matriz audio total
       t %tiempo
       player %función para reproducir y pausar
       posicion
    end
    
    methods (Access = public)
        
        function results = s1(app) 
% El slider de las Fs altas obtiene un lowpass  
            f2=2000
            orde=16;  
            b=fir1(orde,f2/(app.fs/2),"low");
            app.y2=(app.AltosSlider.Value)*filter(b,1,app.y); 
            plot(app.UIAxes2,app.t,app.y4,"black");
% Muestra el audio filtrado en Hz y dB 
        end
        
        function results = s2(app) 
            orde=16
              f7=2000;
              f8=5000;
              b5=fir1(orde,[f7/(app.fs/2) f8/(app.fs/2)],'bandpass');
            app.y3=(app.AltosSlider.Value)*filter(b5,1,app.y);

            plot(app.UIAxes2,app.t,app.y3,"red");
% Muestra el audio filtrado en Hz y dB 
        end
        
        function results = s3(app) 
            orde=16
           cut_off2=15000;
            c=fir1(orde,cut_off2/(app.fs/2),'high');
            app.y4=app.BajosSlider.Value*filter(c,1,app.y);  

            plot(app.UIAxes2,app.t,app.y4,"red");
% Muestra el audio filtrado en Hz y dB 
        end
        
        function results = func2(app)
             app.y=app.y(app.posicion:end)
             if(app.bandas(1)) 
                app.bandas(1)=false;
                app.s1()
             end
             if(app.bandas(2)) 
                app.bandas(1)=false;
                app.s2()
             end
             if(app.bandas(3)) 
                app.bandas(1)=false;
                app.s3()
             end
             
            app.yt=app.y2+app.y3+app.y4;
            app.player = audioplayer(app.yt, app.fs);
            if(isplaying(app.player))
                stop(app.player) 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fullpathname = '/tmp/grabacion.wav'
% Se digitaliza el audio y se usa la transformada para el tiempo            
            info=audioinfo(app.fullpathname);
            [app.y,app.fs]=audioread(app.fullpathname);
            app.t=0:seconds(1/app.fs):seconds(info.Duration);
            app.t=app.t(1:end-1);
%Se muestra el espectro de audio original en Hz         
            plot(app.UIAxes,app.t,app.y);
            app.posicion=1
            app.func2()
        end

        % Button pushed function: BuscarArchivoButton
        function BuscarArchivoButtonPushed(app, event)
% Se selecciona un archivo           
            [filename, pathname]= uigetfile('*.wav*', 'Abre un archivo WAV');
% Se almacena en una variable            
            
        end

        % Value changed function: AltosSlider
        function AltosSliderValueChanged(app, event)
            app.bandas(1)=true;
            app.posicion=get(app.player,'CurrentSample') 
            app.func2()
            play(app.player)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
% Suma las variables y reproduce el audio resultante            
            
app.yt = app.y2 + app.y3 + app.y4;
            app.player= audioplayer (app.yt ,app.fs);
            play(app.player)
        end

        % Value changed function: VolumenKnob
        function VolumenKnobValueChanged(app, event)
            value = app.VolumenKnob.Value;
            app.volume=app.VolumenKnob.Value;
        end

        % Value changed function: MediosSlider
        function MediosSliderValueChanged(app, event)

                app.bandas(2)=true;
            app.posicion=get(app.player,'CurrentSample') 
            app.func2()
            play(app.player)
        end

        % Value changed function: BajosSlider
        function BajosSliderValueChanged(app, event)
            
            app.bandas(3)=true;
            app.posicion=get(app.player,'CurrentSample') 
            app.func2()
            play(app.player)
        end

        % Button pushed function: pausaButton
        function pausaButtonPushed(app, event)
            pause(app.player);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'seg')
            ylabel(app.UIAxes, 'Hz')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Filtrado')
            xlabel(app.UIAxes2, 'seg')
            ylabel(app.UIAxes2, 'Hz')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Altos dB')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Medios dB')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Bajos dB')
            app.BuscarArchivoButton = uibutton(app.UIFigure, 'push');
            app.BuscarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarArchivoButtonPushed, true);
            app.BuscarArchivoButton.Text = 'Buscar Archivo';
            app.AltosSliderLabel = uilabel(app.UIFigure);
            app.AltosSliderLabel.Text = 'Altos';
            app.AltosSlider = uislider(app.UIFigure);
            app.AltosSlider.Limits = [-1 5];
            app.AltosSlider.ValueChangedFcn = createCallbackFcn(app, @AltosSliderValueChanged, true);
            app.AltosSlider.Value = 1;
            app.MediosSliderLabel = uilabel(app.UIFigure);
            app.MediosSliderLabel.Text = 'Medios';
            app.MediosSlider = uislider(app.UIFigure);
            app.MediosSlider.Limits = [-1 5];
            app.MediosSlider.ValueChangedFcn = createCallbackFcn(app, @MediosSliderValueChanged, true);
            app.MediosSlider.Value = 1;
            app.BajosSliderLabel = uilabel(app.UIFigure);
            app.BajosSliderLabel.Text = 'Bajos';
            app.BajosSlider = uislider(app.UIFigure);
            app.BajosSlider.Limits = [-1 5];
            app.BajosSlider.ValueChangedFcn = createCallbackFcn(app, @BajosSliderValueChanged, true);
            app.BajosSlider.Value = 1;
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.VolumenKnobLabel = uilabel(app.UIFigure);
            app.VolumenKnobLabel.Text = 'Volumen';
            app.VolumenKnob = uiknob(app.UIFigure, 'continuous');
            app.VolumenKnob.ValueChangedFcn = createCallbackFcn(app, @VolumenKnobValueChanged, true);
            app.VolumenKnob.Value = 100;
            app.WAVEQrLabel = uilabel(app.UIFigure);
            app.WAVEQrLabel.Text = 'WAV EQ''r';
            app.pausaButton = uibutton(app.UIFigure, 'push');
            app.pausaButton.ButtonPushedFcn = createCallbackFcn(app, @pausaButtonPushed, true);
            app.pausaButton.Text = 'pausa';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end