classdef Luisbraeq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        position            matlab.ui.control.Slider
        MomentoSliderLabel  matlab.ui.control.Label
        Label               matlab.ui.control.Label
        HzLabel_10          matlab.ui.control.Label
        HzLabel_9           matlab.ui.control.Label
        HzLabel_8           matlab.ui.control.Label
        HzLabel_7           matlab.ui.control.Label
        HzLabel_6           matlab.ui.control.Label
        HzLabel_5           matlab.ui.control.Label
        HzLabel_4           matlab.ui.control.Label
        HzLabel_3           matlab.ui.control.Label
        HzLabel_2           matlab.ui.control.Label
        stopButton          matlab.ui.control.Button
        c10                 matlab.ui.control.Slider
        c9                  matlab.ui.control.Slider
        c8                  matlab.ui.control.Slider
        AltosSliderLabel_4  matlab.ui.control.Label
        c7                  matlab.ui.control.Slider
        c6                  matlab.ui.control.Slider
        AltosSliderLabel_3  matlab.ui.control.Label
        c3                  matlab.ui.control.Slider
        c5                  matlab.ui.control.Slider
        c4                  matlab.ui.control.Slider
        volKnob             matlab.ui.control.Knob
        volKnobLabel        matlab.ui.control.Label
        PlayButton          matlab.ui.control.Button
        c2                  matlab.ui.control.Slider
        c1                  matlab.ui.control.Slider
        HzLabel             matlab.ui.control.Label
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fs
        grabacion
        t
        t1
        y
        g1
        g2
        g3
        g4
        g5
        g6
        g7
        g8
        g9
        g10
        Fs
        parar
        Volume
        yT
        player
        y1
        y2
        y3
        y4
        y5
        y6
        y7
        y8
        y9
        y10
        info
        posicion
        moment
        time
    end
    
    methods (Access = public)
        
        function results = func(app)
            value = app.VolumenKnob.Value;
            app.volume=app.VolumenKnob.Value;
        end
        
        function results = func2(app)
            
            app.y=app.y;  
            app.g1=app.c1.Value
            app.g2=app.c2.Value
            app.g3=app.c3.Value
            app.g4=app.c4.Value 
            app.g5=app.c5.Value
            app.g6=app.c6.Value
            app.g7=app.c7.Value
            app.g8=app.c8.Value 
            app.g9=app.c9.Value
            app.g10=app.c10.Value 
            long=size(app.y)


            %Antes de  procesar 
            cut_off=200; % cut off low pass dalama Hz
            orde=16;
            a=fir1(orde,cut_off/(app.Fs/2),'low');
            app.y1=app.g1*filter(a,1,app.y);
            
            % %bandpass1
            f1=201;
            f2=400;
            b1=fir1(orde,[f1/(app.Fs/2) f2/(app.Fs/2)],'bandpass');
            app.y2=app.g2*filter(b1,1,app.y);
            % 
            % %bandpass2
            f3=401;
            f4=800;
            b2=fir1(orde,[f3/(app.Fs/2) f4/(app.Fs/2)],'bandpass');
            app.y3=app.g3*filter(b2,1,app.y);
            % 
            % %bandpass3
             f4=801;
             f5=1500;
             b3=fir1(orde,[f4/(app.Fs/2) f5/(app.Fs/2)],'bandpass');
             app.y4=app.g4*filter(b3,1,app.y);

             % %bandpass4
             f5=1501;
             f6=3000;
             b4=fir1(orde,[f5/(app.Fs/2) f6/(app.Fs/2)],'bandpass');
             app.y5=app.g5*filter(b4,1,app.y);
            % 
            % %bandpass5
              f7=3001;
              f8=5000;
              b5=fir1(orde,[f7/(app.Fs/2) f8/(app.Fs/2)],'bandpass');
              app.y6=app.g6*filter(b5,1,app.y);
            % 
            % %bandpass6
              f9=5001;
              f10=7000;
              b6=fir1(orde,[f9/(app.Fs/2) f10/(app.Fs/2)],'bandpass');
              app.y7=app.g7*filter(b6,1,app.y);
            % 
            % %bandpass7
              f11=7001;
              f12=10000;
              b7=fir1(orde,[f11/(app.Fs/2) f12/(app.Fs/2)],'bandpass');
              app.y8=app.g8*filter(b7,1,app.y);
            % play(app.player)
             % %bandpass8
              f13=10001;
              f14=15000;
              b8=fir1(orde,[f13/(app.Fs/2) f14/(app.Fs/2)],'bandpass');
              app.y9=app.g9*filter(b8,1,app.y);
            % 
             %highpass
            cut_off2=15000;
            c=fir1(orde,cut_off2/(app.Fs/2),'high');
            app.y10=app.g10*filter(c,1,app.y);  
            %app.yT=y1+y2+y3+y4+y5+y6+y7;play(app.player) 
             app.yT=app.y5  +app.y6  +app.y7  +app.y8  +app.y9  +app.y10  ;
             app.player = audioplayer(app.yT(app.posicion:end), app.Fs); 
            
             plot(app.UIAxes_2,app.y2,'r'); 
             plot(app.UIAxes_2,app.y1,'b'); 
             if(isplaying(app.player))
                stop(app.player)
                play(app.player)
                
            end
            
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, t, y, fs)
            app.time=fs 
            app.position.Limits=[0,fs]; 
            app.position.MinorTicks=[0:1:fs] 
            app.position.MajorTicks=[0:1:fs]

            lfrec=[-5,5]
            app.nband.Limits=lfrec
            app.c1.Limits=lfrec
            app.c2.Limits=lfrec
            app.c3.Limits=lfrec
            app.c4.Limits =lfrec
            app.c5.Limits=lfrec
            app.c6.Limits=lfrec
            app.c7.Limits=lfrec
            app.c8.Limits =lfrec
            app.c9.Limits=lfrec
            app.c10.Limits =lfrec


            app.nband.Value=1;
            app.c1.Value=1
            app.c2.Value=1
            app.c3.Value=1
            app.c4.Value =1
            app.c5.Value=1
            app.c6.Value=1
            app.c7.Value=1
            app.c8.Value =1
            app.c9.Value=1
            app.c10.Value =1


            app.nband.MajorTicks=[-5:1:5] 
            app.nband.MinorTicks=[-5:1:5] 
            
            app.c1.MajorTicks=[-5:1:5] 
            app.c2.MajorTicks=[-5:1:5] 
            app.c3.MajorTicks=[-5:1:5] 
            app.c4.MajorTicks=[-5:1:5] 
            app.c5.MajorTicks=[-5:1:5] 
            app.c6.MajorTicks=[-5:1:5] 
            app.c7.MajorTicks=[-5:1:5] 
            app.c8.MajorTicks=[-5:1:5] 
            app.c9.MajorTicks=[-5:1:5] 
            app.c10.MajorTicks=[-5:1:5] 

 
            app.c1.MinorTicks=[-5:0.1:5] 
            app.c2.MinorTicks=[-5:0.1:5] 
            app.c3.MinorTicks=[-5:0.1:5] 
            app.c4.MinorTicks=[-5:0.1:5] 
            app.c5.MinorTicks=[-5:0.1:5] 
            app.c6.MinorTicks=[-5:0.1:5] 
            app.c7.MinorTicks=[-5:0.1:5] 
            app.c8.MinorTicks=[-5:0.1:5] 
            app.c9.MinorTicks=[-5:0.1:5] 
            app.c10.MinorTicks=[-5:0.1:5] 
        end

        % Callback function
        function GrabarButtonPushed(app, event)
            app.fs = 44100; 
            nBits = 16; 
            nChannels = 1; 
            ID = -1;       % default audio input device 
            app.grabacion = audiorecorder(app.fs,nBits,nChannels,ID); 
            record(app.grabacion); 
        end

        % Callback function
        function pararButtonPushed(app, event)
            app.parar=0;
            stop(app.grabacion) 
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
        [app.y,app.Fs] = audioread('grabacion.wav');   
 
        app.posicion=1 
        app.func2()  
        app.parar=1
        play(app.player) 
            
        end

        % Value changed function: c1
        function c1ValueChanged(app, event)
           app.posicion=get(app.player,'CurrentSample') 
            
            if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
        end

        % Value changed function: c2
        function c2ValueChanged(app, event)
           app.posicion=get(app.player,'CurrentSample') 
          if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
           
        end

        % Value changed function: c3
        function c3ValueChanged(app, event)
                 app.posicion=get(app.player,'CurrentSample') 
          if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
           
        end

        % Value changed function: c4
        function c4ValueChanged(app, event)
                  app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
        end

        % Value changed function: c5
        function c5ValueChanged(app, event)
                app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
        end

        % Value changed function: c6
        function c6ValueChanged(app, event)
               app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
           
        end

        % Value changed function: c7
        function c7ValueChanged(app, event)
                app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
           
        end

        % Value changed function: c8
        function c8ValueChanged(app, event)
               app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
           
        end

        % Value changed function: c9
        function c9ValueChanged(app, event)
                app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
        end

        % Value changed function: c10
        function c10ValueChanged(app, event)
                  app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
           
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
                  app.posicion=get(app.player,'CurrentSample') 
            app.func2() 
        end

        % Value changed function: position
        function positionValueChanged(app, event)
          app.moment=app.position.Value
           app.posicion=ceil(size(app.y)*(app.moment/app.time))
           app.func2()
           play(app.player)
        end

        % Callback function
        function nbandValueChanged(app, event)
            value = app.nband.Value;
            app.c1.Value=value
            app.c2.Value=value
            app.c3.Value=value
            app.c4.Value =value
            app.c5.Value=value
            app.c6.Value=value
            app.c7.Value=value
            app.c8.Value =value
            app.c9.Value=value
            app.c10.Value =value
            app.func2()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '0-200  Hz';
            app.c1 = uislider(app.UIFigure);
            app.c1.Limits = [-10 10];
            app.c1.ValueChangedFcn = createCallbackFcn(app, @c1ValueChanged, true);
            app.c1.Value = 1;
            app.c2 = uislider(app.UIFigure);
            app.c2.Limits = [-10 10];
            app.c2.ValueChangedFcn = createCallbackFcn(app, @c2ValueChanged, true);
            app.c2.Value = 1;
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.volKnobLabel = uilabel(app.UIFigure);
            app.volKnobLabel.Text = 'vol';
            app.volKnob = uiknob(app.UIFigure, 'continuous');
            app.c4 = uislider(app.UIFigure);
            app.c4.Limits = [-10 10];
            app.c4.ValueChangedFcn = createCallbackFcn(app, @c4ValueChanged, true);
            app.c5 = uislider(app.UIFigure);
            app.c5.Limits = [-10 10];
            app.c5.ValueChangedFcn = createCallbackFcn(app, @c5ValueChanged, true);
            app.c3 = uislider(app.UIFigure);
            app.c3.Limits = [-10 10];
            app.c3.ValueChangedFcn = createCallbackFcn(app, @c3ValueChanged, true);
            app.AltosSliderLabel_3 = uilabel(app.UIFigure);
            app.AltosSliderLabel_3.Text = 'Altos';
            app.c6 = uislider(app.UIFigure);
            app.c6.Limits = [-10 10];
            app.c6.ValueChangedFcn = createCallbackFcn(app, @c6ValueChanged, true);
            app.c7 = uislider(app.UIFigure);
            app.c7.Limits = [-10 10];
            app.c7.ValueChangedFcn = createCallbackFcn(app, @c7ValueChanged, true);
            app.AltosSliderLabel_4 = uilabel(app.UIFigure);
            app.AltosSliderLabel_4.Text = 'Altos';
            app.c8 = uislider(app.UIFigure);
            app.c8.Limits = [-10 10];
            app.c8.ValueChangedFcn = createCallbackFcn(app, @c8ValueChanged, true);
            app.c9 = uislider(app.UIFigure);
            app.c9.Limits = [-10 10];
            app.c9.ValueChangedFcn = createCallbackFcn(app, @c9ValueChanged, true);
            app.c10 = uislider(app.UIFigure);
            app.c10.Limits = [-10 10];
            app.c10.ValueChangedFcn = createCallbackFcn(app, @c10ValueChanged, true);
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = '0-200  Hz';
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = '0-200  Hz';
            app.HzLabel_4 = uilabel(app.UIFigure);
            app.HzLabel_4.Text = '0-200  Hz';
            app.HzLabel_5 = uilabel(app.UIFigure);
            app.HzLabel_5.Text = '0-200  Hz';
            app.HzLabel_6 = uilabel(app.UIFigure);
            app.HzLabel_6.Text = '0-200  Hz';
            app.HzLabel_7 = uilabel(app.UIFigure);
            app.HzLabel_7.Text = '0-200  Hz';
            app.HzLabel_8 = uilabel(app.UIFigure);
            app.HzLabel_8.Text = '0-200  Hz';
            app.HzLabel_9 = uilabel(app.UIFigure);
            app.HzLabel_9.Text = '0-200  Hz';
            app.HzLabel_10 = uilabel(app.UIFigure);
            app.HzLabel_10.Text = '0-200  Hz';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.MomentoSliderLabel = uilabel(app.UIFigure);
            app.MomentoSliderLabel.Text = 'Momento';
            app.position = uislider(app.UIFigure);
            app.position.Limits = [0 1];
            app.position.MajorTicks = [];
            app.position.ValueChangedFcn = createCallbackFcn(app, @positionValueChanged, true);
            app.position.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Luisbraeq(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end