classdef Luisbraeq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        stopButton          matlab.ui.control.Button
        c10                 matlab.ui.control.Slider
        AltosSliderLabel_5  matlab.ui.control.Label
        c9                  matlab.ui.control.Slider
        BajosSliderLabel_5  matlab.ui.control.Label
        c8                  matlab.ui.control.Slider
        AltosSliderLabel_4  matlab.ui.control.Label
        c7                  matlab.ui.control.Slider
        BajosSliderLabel_4  matlab.ui.control.Label
        c6                  matlab.ui.control.Slider
        AltosSliderLabel_3  matlab.ui.control.Label
        c5                  matlab.ui.control.Slider
        BajosSliderLabel_3  matlab.ui.control.Label
        c4                  matlab.ui.control.Slider
        AltosSliderLabel_2  matlab.ui.control.Label
        c3                  matlab.ui.control.Slider
        BajosSliderLabel_2  matlab.ui.control.Label
        volKnob             matlab.ui.control.Knob
        volKnobLabel        matlab.ui.control.Label
        PlayButton          matlab.ui.control.Button
        c2                  matlab.ui.control.Slider
        AltosSliderLabel    matlab.ui.control.Label
        c1                  matlab.ui.control.Slider
        BajosSliderLabel    matlab.ui.control.Label
        pararButton         matlab.ui.control.Button
        GrabarButton        matlab.ui.control.Button
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fs
        grabacion
        t
        t1
        y
        g1
        g2
        g3
        g4
        g5
        g6
        g7
        g8
        g9
        g10
        Fs
        parar
        Volume
        yT
        player
        y1
        y2
        y3
        y4
        y5
        y6
        y7
        y8
        y9
        y10
        info
        posicion
    end
    
    methods (Access = public)
        
        function results = func(app)
            value = app.VolumenKnob.Value;
            app.volume=app.VolumenKnob.Value;
        end
        
        function results = func2(app)
            
            app.y=app.y;  
            app.g1=app.c1.Value
            app.g2=app.c2.Value
            app.g3=app.c3.Value
            app.g4=app.c4.Value 
            app.g5=app.c5.Value
            app.g6=app.c6.Value
            app.g7=app.c7.Value
            app.g8=app.c8.Value 
            app.g9=app.c9.Value
            app.g10=app.c10.Value 
            long=size(app.y)


            %Antes de  procesar 
            cut_off=200; % cut off low pass dalama Hz
            orde=16;
            a=fir1(orde,cut_off/(app.Fs/2),'low');
            app.y1=app.g1*lowpass(app.y,200,app.Fs)
            
            % %bandpass1
            f1=201;
            f2=400;
            b1=fir1(orde,[f1/(app.Fs/2) f2/(app.Fs/2)],'bandpass');
            app.y2=app.g2*filter(b1,1,app.y);
            % 
            % %bandpass2
            f3=401;
            f4=800;
            b2=fir1(orde,[f3/(app.Fs/2) f4/(app.Fs/2)],'bandpass');
            app.y3=app.g3*filter(b2,1,app.y);
            % 
            % %bandpass3
             f4=801;
             f5=1500;
             b3=fir1(orde,[f4/(app.Fs/2) f5/(app.Fs/2)],'bandpass');
             app.y4=app.g4*filter(b3,1,app.y);

             % %bandpass4
             f5=1501;
             f6=3000;
             b4=fir1(orde,[f5/(app.Fs/2) f6/(app.Fs/2)],'bandpass');
             app.y5=app.g5*filter(b4,1,app.y);
            % 
            % %bandpass5
              f7=3001;
              f8=5000;
              b5=fir1(orde,[f7/(app.Fs/2) f8/(app.Fs/2)],'bandpass');
              app.y6=app.g6*filter(b5,1,app.y);
            % 
            % %bandpass6
              f9=5001;
              f10=7000;
              b6=fir1(orde,[f9/(app.Fs/2) f10/(app.Fs/2)],'bandpass');
              app.y7=app.g7*filter(b6,1,app.y);
            % 
            % %bandpass7
              f11=7001;
              f12=10000;
              b7=fir1(orde,[f11/(app.Fs/2) f12/(app.Fs/2)],'bandpass');
              app.y8=app.g8*filter(b7,1,app.y);
            % play(app.player)
             % %bandpass8
              f13=10001;
              f14=15000;
              b8=fir1(orde,[f13/(app.Fs/2) f14/(app.Fs/2)],'bandpass');
              app.y9=app.g9*filter(b8,1,app.y);
            % 
             %highpass
            cut_off2=15000;
            c=fir1(orde,cut_off2/(app.Fs/2),'high');
            app.y10=app.g10*filter(c,1,app.y);  
            %app.yT=y1+y2+y3+y4+y5+y6+y7;play(app.player) 
             app.yT=app.y1  +app.y2  +app.y3  +app.y4  +app.y5  +app.y6  +app.y7  +app.y8  +app.y9  +app.y10  ;
             app.player = audioplayer(app.yT(app.posicion:end), app.Fs); 
            
             plot(app.UIAxes_2,app.y2,'r'); 
             plot(app.UIAxes_2,app.y1,'b'); 
             if(isplaying(app.player))
                stop(app.player)
                play(app.player)
                
            end
            
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, t, y, fs)
            plot(app.UIAxes,t,y); 
            plot(app.UIAxes_2,t,y,'g'); 
            app.info=fs

        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            app.fs = 44100; 
            nBits = 16; 
            nChannels = 1; 
            ID = -1;       % default audio input device 
            app.grabacion = audiorecorder(app.fs,nBits,nChannels,ID); 
            record(app.grabacion); 
        end

        % Button pushed function: pararButton
        function pararButtonPushed(app, event)
            app.parar=0;
            stop(app.grabacion)
            app.y = getaudiodata(app.grabacion);  
            plot(app.UIAxes,app.t,app.y);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
        [app.y,app.Fs] = audioread('grabacion.wav'); 
        app.posicion=1 
        app.func2()  
        app.parar=1
        play(app.player) 
            
        end

        % Value changed function: c1
        function c1ValueChanged(app, event)
           app.posicion=get(app.player,'CurrentSample') 
            
            if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
        end

        % Value changed function: c2
        function c2ValueChanged(app, event)
           app.posicion=get(app.player,'CurrentSample') 
          if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
           
        end

        % Value changed function: c3
        function c3ValueChanged(app, event)
                 app.posicion=get(app.player,'CurrentSample') 
          if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
           
        end

        % Value changed function: c4
        function c4ValueChanged(app, event)
                  app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
        end

        % Value changed function: c5
        function c5ValueChanged(app, event)
                app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
        end

        % Value changed function: c6
        function c6ValueChanged(app, event)
               app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
           
        end

        % Value changed function: c7
        function c7ValueChanged(app, event)
                app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
           
        end

        % Value changed function: c8
        function c8ValueChanged(app, event)
               app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
           
        end

        % Value changed function: c9
        function c9ValueChanged(app, event)
                app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
        end

        % Value changed function: c10
        function c10ValueChanged(app, event)
                  app.posicion=get(app.player,'CurrentSample') 
if(app.parar==1)  
            stop(app.player)
            app.func2()
            play(app.player)
           end
           
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
                  app.posicion=get(app.player,'CurrentSample') 
            app.func2() 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.GrabarButton = uibutton(app.UIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.pararButton = uibutton(app.UIFigure, 'push');
            app.pararButton.ButtonPushedFcn = createCallbackFcn(app, @pararButtonPushed, true);
            app.pararButton.Text = 'parar';
            app.BajosSliderLabel = uilabel(app.UIFigure);
            app.BajosSliderLabel.Text = 'Bajos';
            app.c1 = uislider(app.UIFigure);
            app.c1.Limits = [-3 3];
            app.c1.ValueChangedFcn = createCallbackFcn(app, @c1ValueChanged, true);
            app.c1.Value = 1;
            app.AltosSliderLabel = uilabel(app.UIFigure);
            app.AltosSliderLabel.Text = 'Altos';
            app.c2 = uislider(app.UIFigure);
            app.c2.ValueChangedFcn = createCallbackFcn(app, @c2ValueChanged, true);
            app.c2.Value = 1;
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.volKnobLabel = uilabel(app.UIFigure);
            app.volKnobLabel.Text = 'vol';
            app.volKnob = uiknob(app.UIFigure, 'continuous');
            app.BajosSliderLabel_2 = uilabel(app.UIFigure);
            app.BajosSliderLabel_2.Text = 'Bajos';
            app.c3 = uislider(app.UIFigure);
            app.c3.ValueChangedFcn = createCallbackFcn(app, @c3ValueChanged, true);
            app.AltosSliderLabel_2 = uilabel(app.UIFigure);
            app.AltosSliderLabel_2.Text = 'Altos';
            app.c4 = uislider(app.UIFigure);
            app.c4.ValueChangedFcn = createCallbackFcn(app, @c4ValueChanged, true);
            app.BajosSliderLabel_3 = uilabel(app.UIFigure);
            app.BajosSliderLabel_3.Text = 'Bajos';
            app.c5 = uislider(app.UIFigure);
            app.c5.ValueChangedFcn = createCallbackFcn(app, @c5ValueChanged, true);
            app.AltosSliderLabel_3 = uilabel(app.UIFigure);
            app.AltosSliderLabel_3.Text = 'Altos';
            app.c6 = uislider(app.UIFigure);
            app.c6.ValueChangedFcn = createCallbackFcn(app, @c6ValueChanged, true);
            app.BajosSliderLabel_4 = uilabel(app.UIFigure);
            app.BajosSliderLabel_4.Text = 'Bajos';
            app.c7 = uislider(app.UIFigure);
            app.c7.ValueChangedFcn = createCallbackFcn(app, @c7ValueChanged, true);
            app.AltosSliderLabel_4 = uilabel(app.UIFigure);
            app.AltosSliderLabel_4.Text = 'Altos';
            app.c8 = uislider(app.UIFigure);
            app.c8.ValueChangedFcn = createCallbackFcn(app, @c8ValueChanged, true);
            app.BajosSliderLabel_5 = uilabel(app.UIFigure);
            app.BajosSliderLabel_5.Text = 'Bajos';
            app.c9 = uislider(app.UIFigure);
            app.c9.ValueChangedFcn = createCallbackFcn(app, @c9ValueChanged, true);
            app.AltosSliderLabel_5 = uilabel(app.UIFigure);
            app.AltosSliderLabel_5.Text = 'Altos';
            app.c10 = uislider(app.UIFigure);
            app.c10.ValueChangedFcn = createCallbackFcn(app, @c10ValueChanged, true);
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Luisbraeq(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end