classdef Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        EliminarButton   matlab.ui.control.Button
        AbrirButton      matlab.ui.control.Button
        PararButton      matlab.ui.control.Button
        GrabarButton     matlab.ui.control.Button
        EcualizarButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sonido=false
        grabando=0
        y
        Fs
        player
        rut
        duracion
        t
    end
    
    methods (Access = private)
         
        
        function results = iniciar(app)
            [app.y,app.Fs] = audioread(app.rut)
            info=audioinfo(app.rut); 
            app.t=0:seconds(1/app.Fs):seconds(info.Duration); 
            app.t=app.t(1:end-1);
%Se muestra el espectro de audio original en Hz         
            plot(app.UIAxes,app.t,app.y);
            app.crear()
            
        end
        
        function results = crear(app)
            display(pwd)
            mkdir(pwd,'tmp');
            filename = 'grabacion.wav';
            audiowrite(filename, app.y, app.Fs)
            info=audioinfo(filename); 
            app.t=0:seconds(1/app.Fs):seconds(info.Duration);
            app.duracion=info.Duration
            app.t=app.t(1:end-1);
            
        end
        
        function results = graf(app) 
%Se muestra el espectro de audio original en Hz         
            plot(app.UIAxes,app.t,app.y);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EcualizarButton
        function EcualizarButtonPushed(app, event)
          if(app.sonido)
             Luisbraeq(app.t,app.y,app.duracion)
          else 
              err('no hay ninguna pista o grabacion')
          end
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
           if(not(app.sonido))
            grabar()
            app.sonido=true;
         else 
             err('sonido seleccionado , borre antes de seleccionar')

         end
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            if(grabando)
            end
            grabando=0
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
         if(not(app.sonido))
             app.sonido=true;
         [FileName,PathName,FilterIndex] = uigetfile({'*.mp3;*.wav;*.wma;*.avi;*.mpg','media files (*.mp3,*.wav,*.wma,*.avi,*.mpg)';'*.*',  'All Files (*.*)'},'File Selector') ; 
         app.rut=[PathName FileName]
         app.iniciar()
         else 
             err('sonido seleccionado , borre antes de seleccionar')

         end
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            app.sonido=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EcualizarButton = uibutton(app.UIFigure, 'push');
            app.EcualizarButton.ButtonPushedFcn = createCallbackFcn(app, @EcualizarButtonPushed, true);
            app.EcualizarButton.Text = 'Ecualizar';
            app.GrabarButton = uibutton(app.UIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.PararButton = uibutton(app.UIFigure, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.AbrirButton = uibutton(app.UIFigure, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end