classdef grabar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        pausarretomarButton  matlab.ui.control.Button
        VolverButton         matlab.ui.control.Button
        finalizarButton      matlab.ui.control.Button
        GrabarButton         matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fs % Description
        grabacion

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            app.fs = 44100; 
            nBits = 16; 
            nChannels = 1; 
            ID = -1;       % default audio input device 
            app.grabacion = audiorecorder(app.fs,nBits,nChannels,ID); 
            record(app.grabacion); 
        end

        % Button pushed function: pausarretomarButton
        function pausarretomarButtonPushed(app, event)
            if isrecording(app.grabacion)
                pause (app.grabacion)
            else
                resume(app.grabacion)
            end
        end

        % Button pushed function: finalizarButton
        function finalizarButtonPushed(app, event)
            stop(app.grabacion)
            y = getaudiodata(app.grabacion); 
            play(app.grabacion) 
            plot(app.UIAxes,y)
            filename = 'grabacion.wav';
            audiowrite(filename, y, app.fs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GrabarButton = uibutton(app.UIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.finalizarButton = uibutton(app.UIFigure, 'push');
            app.finalizarButton.ButtonPushedFcn = createCallbackFcn(app, @finalizarButtonPushed, true);
            app.finalizarButton.Text = 'finalizar';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.pausarretomarButton = uibutton(app.UIFigure, 'push');
            app.pausarretomarButton.ButtonPushedFcn = createCallbackFcn(app, @pausarretomarButtonPushed, true);
            app.pausarretomarButton.Text = 'pausar/retomar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grabar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end